classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Tab                           matlab.ui.container.Tab
        Label                         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        SimulierenButton_3            matlab.ui.control.Button
        Tab_2                         matlab.ui.container.Tab
        GginkNEditFieldLabel          matlab.ui.control.Label
        GginkNEditField               matlab.ui.control.NumericEditField
        linmEditFieldLabel            matlab.ui.control.Label
        linmEditField                 matlab.ui.control.NumericEditField
        GhinkNEditFieldLabel          matlab.ui.control.Label
        GhinkNEditField               matlab.ui.control.NumericEditField
        hsinmEditFieldLabel           matlab.ui.control.Label
        hsinmEditField                matlab.ui.control.NumericEditField
        UIAxes4                       matlab.ui.control.UIAxes
        SimulierenButton              matlab.ui.control.Button
        Label_2                       matlab.ui.control.Label
        Tab_3                         matlab.ui.container.Tab
        Label_3                       matlab.ui.control.Label
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes6_2                     matlab.ui.control.UIAxes
        ChinNradEditFieldLabel        matlab.ui.control.Label
        ChinNradEditField             matlab.ui.control.NumericEditField
        CvinNradEditFieldLabel        matlab.ui.control.Label
        CvinNradEditField             matlab.ui.control.NumericEditField
        minkgEditFieldLabel           matlab.ui.control.Label
        minkgEditField                matlab.ui.control.NumericEditField
        ginms2EditFieldLabel          matlab.ui.control.Label
        ginms2EditField               matlab.ui.control.NumericEditField
        lvinmEditFieldLabel           matlab.ui.control.Label
        lvinmEditField                matlab.ui.control.NumericEditField
        lhinmEditFieldLabel           matlab.ui.control.Label
        lhinmEditField                matlab.ui.control.NumericEditField
        SimulierenButton_2            matlab.ui.control.Button
        Tab_4                         matlab.ui.container.Tab
        HopplahieristwohlnichtsLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SimulierenButton
        function SimulierenButtonPushed(app, event)
            plot3_7UIAxes(app.UIAxes4, app.GginkNEditField.Value, app.GhinkNEditField.Value, app.linmEditField.Value, app.hsinmEditField.Value)
        end

        % Button pushed function: SimulierenButton_2
        function SimulierenButton_2Pushed(app, event)
            plot4_6_1UIAxes(app.UIAxes6, app.UIAxes6_2, app.ChinNradEditField.Value, app.CvinNradEditField.Value, app.lvinmEditField.Value, app.lhinmEditField.Value, app.minkgEditField.Value, app.ginms2EditField.Value)
        end

        % Button pushed function: SimulierenButton_3
        function SimulierenButton_3Pushed(app, event)
            plot3_3UIAxes(app.UIAxes, app.UIAxes2, app.UIAxes3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '3.3';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '3.3';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.Tab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.Tab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.SimulierenButton_3 = uibutton(app.Tab, 'push');
            app.SimulierenButton_3.ButtonPushedFcn = createCallbackFcn(app, @SimulierenButton_3Pushed, true);
            app.SimulierenButton_3.Text = 'Simulieren';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '3.7';
            app.GginkNEditFieldLabel = uilabel(app.Tab_2);
            app.GginkNEditFieldLabel.Text = 'Gg in kN';
            app.GginkNEditField = uieditfield(app.Tab_2, 'numeric');
            app.GginkNEditField.Value = 16.59;
            app.linmEditFieldLabel = uilabel(app.Tab_2);
            app.linmEditFieldLabel.Text = 'l in m';
            app.linmEditField = uieditfield(app.Tab_2, 'numeric');
            app.linmEditField.Value = 2.8;
            app.GhinkNEditFieldLabel = uilabel(app.Tab_2);
            app.GhinkNEditFieldLabel.Text = 'Gh in kN';
            app.GhinkNEditField = uieditfield(app.Tab_2, 'numeric');
            app.GhinkNEditField.Value = 8.17;
            app.hsinmEditFieldLabel = uilabel(app.Tab_2);
            app.hsinmEditFieldLabel.Text = 'hs in m';
            app.hsinmEditField = uieditfield(app.Tab_2, 'numeric');
            app.hsinmEditField.Value = 0.5;
            app.UIAxes4 = uiaxes(app.Tab_2);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.SimulierenButton = uibutton(app.Tab_2, 'push');
            app.SimulierenButton.ButtonPushedFcn = createCallbackFcn(app, @SimulierenButtonPushed, true);
            app.SimulierenButton.Text = 'Simulieren';
            app.Label_2 = uilabel(app.Tab_2);
            app.Label_2.Text = '3.7';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '4.6';
            app.Label_3 = uilabel(app.Tab_3);
            app.Label_3.Text = '4.6';
            app.UIAxes6 = uiaxes(app.Tab_3);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            app.UIAxes6_2 = uiaxes(app.Tab_3);
            title(app.UIAxes6_2, 'Title')
            xlabel(app.UIAxes6_2, 'X')
            ylabel(app.UIAxes6_2, 'Y')
            app.ChinNradEditFieldLabel = uilabel(app.Tab_3);
            app.ChinNradEditFieldLabel.Text = 'Ch in N/rad';
            app.ChinNradEditField = uieditfield(app.Tab_3, 'numeric');
            app.ChinNradEditField.Value = 150000;
            app.CvinNradEditFieldLabel = uilabel(app.Tab_3);
            app.CvinNradEditFieldLabel.Text = 'Cv in N/rad';
            app.CvinNradEditField = uieditfield(app.Tab_3, 'numeric');
            app.CvinNradEditField.Value = 75000;
            app.minkgEditFieldLabel = uilabel(app.Tab_3);
            app.minkgEditFieldLabel.Text = 'm in kg';
            app.minkgEditField = uieditfield(app.Tab_3, 'numeric');
            app.minkgEditField.Value = 1550;
            app.ginms2EditFieldLabel = uilabel(app.Tab_3);
            app.ginms2EditFieldLabel.Text = 'g in m/s2';
            app.ginms2EditField = uieditfield(app.Tab_3, 'numeric');
            app.ginms2EditField.Value = 9.81;
            app.lvinmEditFieldLabel = uilabel(app.Tab_3);
            app.lvinmEditFieldLabel.Text = 'lv in m';
            app.lvinmEditField = uieditfield(app.Tab_3, 'numeric');
            app.lvinmEditField.Value = 1.344;
            app.lhinmEditFieldLabel = uilabel(app.Tab_3);
            app.lhinmEditFieldLabel.Text = 'lh in m';
            app.lhinmEditField = uieditfield(app.Tab_3, 'numeric');
            app.lhinmEditField.Value = 1.456;
            app.SimulierenButton_2 = uibutton(app.Tab_3, 'push');
            app.SimulierenButton_2.ButtonPushedFcn = createCallbackFcn(app, @SimulierenButton_2Pushed, true);
            app.SimulierenButton_2.Text = 'Simulieren';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '4.8';
            app.HopplahieristwohlnichtsLabel = uilabel(app.Tab_4);
            app.HopplahieristwohlnichtsLabel.Text = 'Hoppla, hier ist wohl nichts :(';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end